classdef gui_stl_slice_and_plotV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ApplyLimitsButton_2            matlab.ui.control.Button
        ApplyLimitsButton              matlab.ui.control.Button
        ReadFileButton                 matlab.ui.control.Button
        RotationaxisButtonGroup        matlab.ui.container.ButtonGroup
        xButton                        matlab.ui.control.RadioButton
        yButton                        matlab.ui.control.RadioButton
        zButton                        matlab.ui.control.RadioButton
        RotateButton                   matlab.ui.control.Button
        ASCIIfileCheckBox              matlab.ui.control.CheckBox
        textfield_stl_filename         matlab.ui.control.EditField
        STLFileLabel                   matlab.ui.control.Label
        numericField_slice_height      matlab.ui.control.NumericEditField
        SliceheightEditFieldLabel      matlab.ui.control.Label
        RotationangleEditField         matlab.ui.control.NumericEditField
        RotationangleEditFieldLabel    matlab.ui.control.Label
        PlotdelayEditField             matlab.ui.control.NumericEditField
        PlotdelayEditFieldLabel        matlab.ui.control.Label
        XminEditField                  matlab.ui.control.NumericEditField
        XminEditFieldLabel             matlab.ui.control.Label
        YminEditField                  matlab.ui.control.NumericEditField
        YminEditFieldLabel             matlab.ui.control.Label
        ZminEditField                  matlab.ui.control.NumericEditField
        ZminEditFieldLabel             matlab.ui.control.Label
        XmaxEditField                  matlab.ui.control.NumericEditField
        XmaxEditFieldLabel             matlab.ui.control.Label
        YmaxEditField                  matlab.ui.control.NumericEditField
        YmaxEditFieldLabel             matlab.ui.control.Label
        ZmaxEditField                  matlab.ui.control.NumericEditField
        ZmaxEditFieldLabel             matlab.ui.control.Label
        XminEditField_2                matlab.ui.control.NumericEditField
        XminEditField_2Label           matlab.ui.control.Label
        YminEditField_2                matlab.ui.control.NumericEditField
        YminEditField_2Label           matlab.ui.control.Label
        ZminEditField_2                matlab.ui.control.NumericEditField
        ZminEditField_2Label           matlab.ui.control.Label
        XmaxEditField_2                matlab.ui.control.NumericEditField
        XmaxEditField_2Label           matlab.ui.control.Label
        YmaxEditField_2                matlab.ui.control.NumericEditField
        YmaxEditField_2Label           matlab.ui.control.Label
        ZmaxEditField_2                matlab.ui.control.NumericEditField
        ZmaxEditField_2Label           matlab.ui.control.Label
        SliceButton                    matlab.ui.control.Button
        button_browse_stl_filename     matlab.ui.control.Button
        ElectroHydrodynamicsSlicersLabel  matlab.ui.control.Label
        SaverFigureButton              matlab.ui.control.Button
        CreatingWaypointsButton        matlab.ui.control.Button
        ScaleEditField                 matlab.ui.control.NumericEditField
        ScaleEditFieldLabel            matlab.ui.control.Label
        stepstraverldistanceEditField  matlab.ui.control.NumericEditField
        stepstraverldistanceEditFieldLabel  matlab.ui.control.Label
        LayerViewer                    matlab.ui.control.UIAxes
        STLViewer                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        triangles % Triangles_original
        mod_triangles %triangles modified
        plot_vals_stl_data;
        ui1pan;
        ui1zoom;
        ui1rotate;
        ui2rotate;
        ui2zoom;
        ui2pan;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: button_browse_stl_filename
        function button_browse_stl_filenamePushed(app, event)
            [file, path] = uigetfile('*.stl');
            full_file_path_name = strcat(path,file);
            %app.textfield_stl_filename.Text = full_file_path_name;
            app.textfield_stl_filename.Value = full_file_path_name;            
        end

        % Button pushed function: SliceButton
        function SliceButtonPushed(app, event)
            %hold(app.LayerViewer,'on')
            slice_height = app.numericField_slice_height.Value;
            tic;[movelist, z_slices] = slice_stl_create_path(app.mod_triangles, slice_height);toc;
            plot_slices_app(app.LayerViewer, movelist, z_slices, app.PlotdelayEditField.Value);
            hold(app.LayerViewer,'off')
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            axis = app.RotationaxisButtonGroup.SelectedObject.Text;
            app.mod_triangles = rotate_stl(app.mod_triangles,axis,app.RotationangleEditField.Value);
            %v = reshape(app.mod_triangles(:,1:9)',3,size(app.mod_triangles,1)*3)';
            plot_stl_app(app.STLViewer,app.mod_triangles);
          
        end

        % Button pushed function: ReadFileButton
        function ReadFileButtonPushed(app, event)
             filename = app.textfield_stl_filename.Value;
            if app.ASCIIfileCheckBox.Value == 0
                app.triangles = read_binary_stl_file(filename);
                app.mod_triangles = app.triangles;
            else
                app.triangles = read_ascii_stl(filename,1);
                app.mod_triangles = app.triangles;
            end
            %v = reshape(app.mod_triangles(:,1:9)',3,size(app.mod_triangles,1)*3)';
            plot_stl_app(app.STLViewer, app.mod_triangles);   
            
        end

        % Callback function
        function PanButtonPushed(app, event)
           pan(app.STLViewer,'on'); 
        end

        % Callback function
        function ZoomButtonPushed(app, event)
            zoom(app.STLViewer,'on');
        end

        % Callback function
        function RotateButton_2Pushed(app, event)
            rotate3d(app.STLViewer,'on');
        end

        % Callback function
        function PanButton_2Pushed(app, event)
            pan(app.LayerViewer,'on'); 
        end

        % Callback function
        function ZoomButton_2Pushed(app, event)
            zoom(app.LayerViewer,'on');
        end

        % Callback function
        function RotateButton_3Pushed(app, event)
            rotate3d(app.LayerViewer,'on');
        end

        % Value changed function: XminEditField
        function XminEditFieldValueChanged(app, event)
            x_limits = app.LayerViewer.XLim;
            app.LayerViewer.XLim = [app.XminEditField.Value, x_limits(2)];
        end

        % Value changed function: XmaxEditField
        function XmaxEditFieldValueChanged(app, event)
            x_limits = app.LayerViewer.XLim;
            app.LayerViewer.XLim = [x_limits(1), app.XmaxEditField.Value];
        end

        % Value changed function: YminEditField
        function YminEditFieldValueChanged(app, event)
            y_limits = app.LayerViewer.YLim;
            app.LayerViewer.YLim = [app.YminEditField.Value, y_limits(2)];
        end

        % Value changed function: YmaxEditField
        function YmaxEditFieldValueChanged(app, event)
            y_limits = app.LayerViewer.YLim;
            app.LayerViewer.YLim = [y_limits(1), app.YmaxEditField.Value];            
        end

        % Value changed function: ZminEditField
        function ZminEditFieldValueChanged(app, event)
            z_limits = app.LayerViewer.ZLim;
            app.LayerViewer.ZLim = [app.ZminEditField.Value, z_limits(2)];            
        end

        % Value changed function: ZmaxEditField
        function ZmaxEditFieldValueChanged(app, event)
            z_limits = app.LayerViewer.ZLim;
            app.LayerViewer.ZLim = [z_limits(1), app.ZmaxEditField.Value];            
        end

        % Value changed function: XminEditField_2
        function XminEditField_2ValueChanged(app, event)
            x_limits = app.STLViewer.XLim;
            app.STLViewer.XLim = [app.XminEditField_2.Value, x_limits(2)];            
        end

        % Value changed function: YminEditField_2
        function YminEditField_2ValueChanged(app, event)
            y_limits = app.STLViewer.YLim;
            app.STLViewer.YLim = [app.YminEditField_2.Value, y_limits(2)];
        end

        % Value changed function: ZminEditField_2
        function ZminEditField_2ValueChanged(app, event)
             z_limits = app.STLViewer.ZLim;
             app.STLViewer.ZLim = [app.ZminEditField_2.Value, z_limits(2)]; 
        end

        % Value changed function: XmaxEditField_2
        function XmaxEditField_2ValueChanged(app, event)
             x_limits = app.STLViewer.XLim;
            app.STLViewer.XLim = [x_limits(1), app.XmaxEditField_2.Value];            
        end

        % Value changed function: YmaxEditField_2
        function YmaxEditField_2ValueChanged(app, event)
            y_limits = app.STLViewer.YLim;
            app.STLViewer.YLim = [y_limits(1), app.YmaxEditField_2.Value];             
        end

        % Value changed function: ZmaxEditField_2
        function ZmaxEditField_2ValueChanged(app, event)
            z_limits = app.STLViewer.ZLim;
            app.STLViewer.ZLim = [z_limits(1), app.ZmaxEditField_2.Value];              
        end

        % Button pushed function: ApplyLimitsButton
        function ApplyLimitsButtonPushed(app, event)
            app.LayerViewer.XLim = [app.XminEditField.Value, app.XmaxEditField.Value];
            app.LayerViewer.YLim = [app.YminEditField.Value, app.YmaxEditField.Value];
            app.LayerViewer.ZLim = [app.XminEditField.Value, app.ZmaxEditField.Value];
        end

        % Button pushed function: ApplyLimitsButton_2
        function ApplyLimitsButton_2Pushed(app, event)
            app.STLViewer.XLim = [app.XminEditField_2.Value, app.XmaxEditField_2.Value];
            app.STLViewer.YLim = [app.YminEditField_2.Value, app.YmaxEditField_2.Value];
            app.STLViewer.ZLim = [app.XminEditField_2.Value, app.ZmaxEditField_2.Value];
        end

        % Button pushed function: SaverFigureButton
        function SaverFigureButtonPushed(app, event)
            slice_height = app.numericField_slice_height.Value;
            tic;[movelist, z_slices] = slice_stl_create_path(app.mod_triangles, slice_height);toc;
            slices_save_app(movelist,z_slices, app.PlotdelayEditField.Value)
        end

        % Button pushed function: CreatingWaypointsButton
        function CreatingWaypointsButtonPushed(app, event)
            moveList = evalin('base','Movelist');
            [wayPoints, steps] = wayPoint_creating(moveList, app.ScaleEditField.Value, app.stepstraverldistanceEditField.Value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ApplyLimitsButton_2 = uibutton(app.UIFigure, 'push');
            app.ApplyLimitsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyLimitsButton_2Pushed, true);
            app.ApplyLimitsButton_2.Text = 'ApplyLimits';
            app.ApplyLimitsButton = uibutton(app.UIFigure, 'push');
            app.ApplyLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLimitsButtonPushed, true);
            app.ApplyLimitsButton.Text = 'ApplyLimits';
            app.ReadFileButton = uibutton(app.UIFigure, 'push');
            app.ReadFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFileButtonPushed, true);
            app.ReadFileButton.Text = 'ReadFile';
            app.RotationaxisButtonGroup = uibuttongroup(app.UIFigure);
            app.RotationaxisButtonGroup.Title = 'Rotation axis';
            app.xButton = uiradiobutton(app.RotationaxisButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.RotationaxisButtonGroup);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.RotationaxisButtonGroup);
            app.zButton.Text = 'z';
            app.RotateButton = uibutton(app.RotationaxisButtonGroup, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.ASCIIfileCheckBox = uicheckbox(app.UIFigure);
            app.ASCIIfileCheckBox.Text = 'ASCII file';
            app.textfield_stl_filename = uieditfield(app.UIFigure, 'text');
            app.textfield_stl_filename.Value = 'Version2.STL';
            app.STLFileLabel = uilabel(app.UIFigure);
            app.STLFileLabel.Text = 'STL File';
            app.numericField_slice_height = uieditfield(app.UIFigure, 'numeric');
            app.numericField_slice_height.Value = 0.1;
            app.SliceheightEditFieldLabel = uilabel(app.UIFigure);
            app.SliceheightEditFieldLabel.Text = 'Slice height';
            app.RotationangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationangleEditField.Limits = [0 360];
            app.RotationangleEditField.Value = 90;
            app.RotationangleEditFieldLabel = uilabel(app.UIFigure);
            app.RotationangleEditFieldLabel.Text = 'Rotation angle';
            app.PlotdelayEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotdelayEditField.Limits = [0 360];
            app.PlotdelayEditField.Value = 0.0001;
            app.PlotdelayEditFieldLabel = uilabel(app.UIFigure);
            app.PlotdelayEditFieldLabel.Text = 'Plot delay';
            app.XminEditField = uieditfield(app.UIFigure, 'numeric');
            app.XminEditField.Limits = [0 2600];
            app.XminEditField.ValueChangedFcn = createCallbackFcn(app, @XminEditFieldValueChanged, true);
            app.XminEditFieldLabel = uilabel(app.UIFigure);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.YminEditField = uieditfield(app.UIFigure, 'numeric');
            app.YminEditField.Limits = [0 2600];
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YminEditFieldValueChanged, true);
            app.YminEditFieldLabel = uilabel(app.UIFigure);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.ZminEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZminEditField.Limits = [0 2600];
            app.ZminEditField.ValueChangedFcn = createCallbackFcn(app, @ZminEditFieldValueChanged, true);
            app.ZminEditFieldLabel = uilabel(app.UIFigure);
            app.ZminEditFieldLabel.Text = 'Zmin';
            app.XmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmaxEditField.Limits = [0 2600];
            app.XmaxEditField.ValueChangedFcn = createCallbackFcn(app, @XmaxEditFieldValueChanged, true);
            app.XmaxEditField.Value = 170;
            app.XmaxEditFieldLabel = uilabel(app.UIFigure);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.YmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditField.Limits = [0 2600];
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxEditFieldValueChanged, true);
            app.YmaxEditField.Value = 25;
            app.YmaxEditFieldLabel = uilabel(app.UIFigure);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.ZmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZmaxEditField.Limits = [0 2600];
            app.ZmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ZmaxEditFieldValueChanged, true);
            app.ZmaxEditField.Value = 45;
            app.ZmaxEditFieldLabel = uilabel(app.UIFigure);
            app.ZmaxEditFieldLabel.Text = 'Zmax';
            app.XminEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XminEditField_2.Limits = [-10 360];
            app.XminEditField_2.ValueChangedFcn = createCallbackFcn(app, @XminEditField_2ValueChanged, true);
            app.XminEditField_2Label = uilabel(app.UIFigure);
            app.XminEditField_2Label.Text = 'Xmin';
            app.YminEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YminEditField_2.Limits = [-10 360];
            app.YminEditField_2.ValueChangedFcn = createCallbackFcn(app, @YminEditField_2ValueChanged, true);
            app.YminEditField_2Label = uilabel(app.UIFigure);
            app.YminEditField_2Label.Text = 'Ymin';
            app.ZminEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZminEditField_2.Limits = [-10 360];
            app.ZminEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZminEditField_2ValueChanged, true);
            app.ZminEditField_2Label = uilabel(app.UIFigure);
            app.ZminEditField_2Label.Text = 'Zmin';
            app.XmaxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XmaxEditField_2.Limits = [0 2600];
            app.XmaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @XmaxEditField_2ValueChanged, true);
            app.XmaxEditField_2.Value = 170;
            app.XmaxEditField_2Label = uilabel(app.UIFigure);
            app.XmaxEditField_2Label.Text = 'Xmax';
            app.YmaxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditField_2.Limits = [0 2600];
            app.YmaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @YmaxEditField_2ValueChanged, true);
            app.YmaxEditField_2.Value = 50;
            app.YmaxEditField_2Label = uilabel(app.UIFigure);
            app.YmaxEditField_2Label.Text = 'Ymax';
            app.ZmaxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZmaxEditField_2.Limits = [0 2600];
            app.ZmaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZmaxEditField_2ValueChanged, true);
            app.ZmaxEditField_2.Value = 45;
            app.ZmaxEditField_2Label = uilabel(app.UIFigure);
            app.ZmaxEditField_2Label.Text = 'Zmax';
            app.SliceButton = uibutton(app.UIFigure, 'push');
            app.SliceButton.ButtonPushedFcn = createCallbackFcn(app, @SliceButtonPushed, true);
            app.SliceButton.Text = 'Slice';
            app.button_browse_stl_filename = uibutton(app.UIFigure, 'push');
            app.button_browse_stl_filename.ButtonPushedFcn = createCallbackFcn(app, @button_browse_stl_filenamePushed, true);
            app.button_browse_stl_filename.Text = 'Browse';
            app.ElectroHydrodynamicsSlicersLabel = uilabel(app.UIFigure);
            app.ElectroHydrodynamicsSlicersLabel.Text = 'ElectroHydrodynamics Slicers';
            app.SaverFigureButton = uibutton(app.UIFigure, 'push');
            app.SaverFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaverFigureButtonPushed, true);
            app.SaverFigureButton.Text = 'Saver Figure';
            app.CreatingWaypointsButton = uibutton(app.UIFigure, 'push');
            app.CreatingWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @CreatingWaypointsButtonPushed, true);
            app.CreatingWaypointsButton.Text = 'Creating Waypoints';
            app.ScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField.Limits = [0 1000];
            app.ScaleEditField.Value = 0.01;
            app.ScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.stepstraverldistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.stepstraverldistanceEditField.Limits = [0 1];
            app.stepstraverldistanceEditField.Value = 0.00015625;
            app.stepstraverldistanceEditFieldLabel = uilabel(app.UIFigure);
            app.stepstraverldistanceEditFieldLabel.Text = 'steps traverl distance';
            app.LayerViewer = uiaxes(app.UIFigure);
            title(app.LayerViewer, 'Layer Preview')
            xlabel(app.LayerViewer, 'X')
            ylabel(app.LayerViewer, 'Y')
            app.LayerViewer.XLim = [0 2600];
            app.LayerViewer.YLim = [0 2600];
            app.LayerViewer.ZLim = [0 2];
            app.STLViewer = uiaxes(app.UIFigure);
            title(app.STLViewer, 'STL Preview')
            xlabel(app.STLViewer, 'X')
            ylabel(app.STLViewer, 'Y')
            zlabel(app.STLViewer, 'Z')
            app.STLViewer.XLim = [0 2600];
            app.STLViewer.YLim = [0 2600];
            app.STLViewer.ZLim = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_stl_slice_and_plotV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end